classdef EMG_Comparison_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        StatusLabel     matlab.ui.control.Label
        AnalyzeButton   matlab.ui.control.Button
        LoadFileButton  matlab.ui.control.Button
        UIAxesCorr      matlab.ui.control.UIAxes
        UIAxesNorm      matlab.ui.control.UIAxes
        UIAxesOriginal  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        FilePath        string    % Store selected file path
        EMGData         struct    % Store loaded EMG data
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Open file selection dialog for .xlsx files
            [file, path] = uigetfile({'*.xlsx', 'Excel Files (*.xlsx)'}, 'Select an EMG Data File');

            if isequal(file, 0)
                app.StatusLabel.Text = 'No file selected.';
                return;
            end

            % Store the file path in the app for later use
            app.FilePath = fullfile(path, file);
            app.StatusLabel.Text = ['Loaded: ', file];

            % Debug: Display the selected file path
            disp(['User selected file: ', app.FilePath]);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if isempty(app.FilePath)
                app.StatusLabel.Text = 'Please, load a file first.';
                return;
            end

            % Load User's .xlsx File
            file_path = app.FilePath;
            raw_data = readmatrix(file_path);
            time_vector = raw_data(:, 1); % First column is time
            emg_data = raw_data(:, 2:end); % Remaining columns are EMG channels

            [num_samples, num_channels] = size(emg_data);

            % Apply Filtering and Extract Envelope (Based on a1_event_detection.m)
            Fs = 2148; % Sampling frequency
            low_cut = 20; high_cut = 450; order = 4;
            [b, a] = butter(order/2, [low_cut, high_cut] / (Fs/2), 'bandpass');

            % Adaptive Notch Filter for 50 Hz
            wo = 50 / (Fs/2);
            bw = wo / 35;
            [bn, an] = iirnotch(wo, bw);

            % Initialize matrices
            filtered_emg = zeros(size(emg_data));
            envelope_emg = zeros(size(emg_data));

            for i = 1:num_channels
                emg_data(:, i) = filtfilt(bn, an, emg_data(:, i)); % Notch Filter
                filtered_emg(:, i) = filtfilt(b, a, emg_data(:, i)); % Bandpass Filter
                rectified_emg = abs(filtered_emg(:, i)); % Rectification
                [b_env, a_env] = butter(4, 8 / (Fs/2), 'low'); % Envelope Extraction
                envelope_emg(:, i) = filtfilt(b_env, a_env, rectified_emg);
            end

            % Normalize the Extracted Envelope Data (Based on b_normalization.m)
            num_points = 1000;
            normalized_data = struct();

            for i = 1:num_channels
                muscle_name = sprintf('muscle_%d', i);
                envelope_signal = envelope_emg(:, i);

                % Normalize in amplitude
                min_val = min(envelope_signal);
                max_val = max(envelope_signal);
                if max_val > min_val
                    envelope_signal = (envelope_signal - min_val) / (max_val - min_val);
                end

                % Normalize in time
                original_time = linspace(0, 100, length(envelope_signal));
                normalized_time = linspace(0, 100, num_points);
                normalized_envelope = interp1(original_time, envelope_signal, normalized_time, 'pchip');

                % Store normalized data
                normalized_data.(muscle_name) = normalized_envelope;
            end

            % Load the Reference EMG Data (representative_emg_curve.mat)
            reference_file = 'C:\Users\sergi\Documents\CEU\TFG\results\mean\representative_emg_curve.mat';

            if exist(reference_file, 'file') ~= 2
                app.StatusLabel.Text = 'Reference file not found.';
                return;
            end

            reference_data = load(reference_file);
            if ~isfield(reference_data, 'emg_results')
                app.StatusLabel.Text = 'Reference data is invalid.';
                return;
            end
            reference_emg = reference_data.emg_results;

            % Compute Cross-Correlation Between User Data and Reference Data
            max_lag = 50;
            correlation_results = struct();

            for i = 1:num_channels
                muscle_name = sprintf('muscle_%d', i);

                % Extract User's Normalized EMG Data
                if isfield(normalized_data, muscle_name)
                    user_signal = normalized_data.(muscle_name);
                else
                    continue;
                end

                % Extract Reference Mean EMG Data
                if isfield(reference_emg, muscle_name) && isfield(reference_emg.(muscle_name), 'mean')
                    ref_signal = reference_emg.(muscle_name).mean;
                else
                    app.StatusLabel.Text = ['Error: "mean" missing for ', muscle_name, ' in reference file.'];
                    return;
                end

                % Ensure signals are the same length
                min_length = min(length(user_signal), length(ref_signal));
                user_signal = user_signal(1:min_length);
                ref_signal = ref_signal(1:min_length);

                % Compute Cross-Correlation
                [xcorr_values, lags] = xcorr(user_signal, ref_signal, max_lag, 'coeff');
                correlation_results.(muscle_name) = max(xcorr_values);
            end

            % Plot the Results
            muscle_to_plot = 'muscle_1';
            if isfield(normalized_data, muscle_to_plot) && isfield(reference_emg, muscle_to_plot)
                plot(app.UIAxesOriginal, envelope_emg(:, 1));
                title(app.UIAxesOriginal, 'User EMG Envelope');
                xlabel(app.UIAxesOriginal, 'Samples');
                ylabel(app.UIAxesOriginal, 'Amplitude');

                plot(app.UIAxesNorm, reference_emg.(muscle_to_plot).mean);
                title(app.UIAxesNorm, 'Reference EMG Mean');
                xlabel(app.UIAxesNorm, 'Samples');
                ylabel(app.UIAxesNorm, 'Amplitude');

                bar(app.UIAxesCorr, cell2mat(struct2cell(correlation_results)));
                title(app.UIAxesCorr, 'Cross-Correlation with Reference');
                xticks(1:num_channels);
                xticklabels(arrayfun(@(x) sprintf('muscle_%d', x), 1:num_channels, 'UniformOutput', false));
                ylabel(app.UIAxesCorr, 'Correlation Coefficient');
            end

            % Update Status Label
            app.StatusLabel.Text = 'Analysis completed.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'Title')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesNorm = uiaxes(app.UIFigure);
            title(app.UIAxesNorm, 'Title')
            xlabel(app.UIAxesNorm, 'X')
            ylabel(app.UIAxesNorm, 'Y')
            zlabel(app.UIAxesNorm, 'Z')
            app.UIAxesCorr = uiaxes(app.UIFigure);
            title(app.UIAxesCorr, 'Title')
            xlabel(app.UIAxesCorr, 'X')
            ylabel(app.UIAxesCorr, 'Y')
            zlabel(app.UIAxesCorr, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Select a file to start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_Comparison_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end